classdef TsetlinGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        HistoryButton                   matlab.ui.control.Button
        SweepTarget                     matlab.ui.control.DropDown
        SweepTargetLabel                matlab.ui.control.Label
        SweepOrderLabel                 matlab.ui.control.Label
        SweepOrder                      matlab.ui.control.DropDown
        ShowEpochs                      matlab.ui.control.EditField
        ShowEpochsLabel                 matlab.ui.control.Label
        SensitivityState                matlab.ui.control.Label
        ThresholdState                  matlab.ui.control.Label
        TrainLabel                      matlab.ui.control.Label
        StepLabel_2                     matlab.ui.control.Label
        ToLabel_2                       matlab.ui.control.Label
        StepLabel                       matlab.ui.control.Label
        ToLabel                         matlab.ui.control.Label
        TestfilehasnotbeenselectedLabel  matlab.ui.control.Label
        SensitivityStep                 matlab.ui.control.EditField
        ThresholdStep                   matlab.ui.control.EditField
        TrainingfilehasnotbeenselectedLabel  matlab.ui.control.Label
        SelectTestFileButton            matlab.ui.control.Button
        SelectedFileLabel               matlab.ui.control.Button
        TrainLamp                       matlab.ui.control.Lamp
        ResetButton                     matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        TrainButton                     matlab.ui.control.Button
        Switch2                         matlab.ui.control.Switch
        Epochs_1                        matlab.ui.control.EditField
        EpochsEditFieldLabel            matlab.ui.control.Label
        NumberofClasses_1               matlab.ui.control.EditField
        NumberofFeatures_1              matlab.ui.control.EditField
        NumberofFeaturesEditFieldLabel  matlab.ui.control.Label
        NumberofClassesEditFieldLabel   matlab.ui.control.Label
        NumberofStates_1                matlab.ui.control.EditField
        NumberofStatesEditFieldLabel    matlab.ui.control.Label
        NumberofClauses_1               matlab.ui.control.EditField
        NumberofClausesEditFieldLabel   matlab.ui.control.Label
        Sensitivity_1                   matlab.ui.control.EditField
        SensitivitySLabel               matlab.ui.control.Label
        Threshold_1                     matlab.ui.control.EditField
        ThresholdTEditFieldLabel        matlab.ui.control.Label
        Sensitivity_2                   matlab.ui.control.EditField
        Threshold_2                     matlab.ui.control.EditField
        ControlPanelLabel               matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

        properties (Access = public)
            SelectedFile
            XTrain
            yTrain
            TrainedModel
            XTest
            yTest
        end
        
        properties (Access = private)
            IsTraining = false
            stopFlag = false % ใช้สำหรับกดหยุด
            hTrain     matlab.graphics.chart.primitive.Line
            LampTimer  % ใช้หน่วง xxx วิ ก่อนเปลี่ยนเป็นสีเทา
            CurrentT double = NaN;
            CurrentS double = NaN;
            PendingClose logical = false
            ResultsDir char = ''   % โฟลเดอร์เก็บรูปผลลัพธ์
            RunIdx double = 0      % ตัวนับรอบการรัน

            % ===== Logging =====
            HistoryDir    string = fullfile(string(userpath), "TsetlinGUI", "history");
            HistoryXlsx   string = fullfile(string(userpath), "TsetlinGUI", "history", "results.xlsx");
            HistoryCsv    string = fullfile(string(userpath), "TsetlinGUI", "history", "results.csv");
        end
    
        % ===== Helpers =====
        methods (Access = private)
            function [M, errmsg] = readFlexibleFile(app, filepath)
                % อ่านเป็น numeric matrix แบบยืดหยุ่น:
                % 1) ลอง readmatrix (CSV/Excel มาตรฐาน)
                % 2) Fallback: ไฟล์ที่ทั้งบรรทัดถูกครอบด้วย "..." และคั่นด้วยช่องว่าง
                errmsg = "";
                M = [];
            
                % ---------- 1) พยายามอ่านแบบปกติ ----------
                try
                    M = readmatrix(filepath);
                    if ~isempty(M) && size(M,2) >= 2 && isnumeric(M) && all(isfinite(M), 'all')
                        return;
                    end
                catch
                    % ไปต่อ fallback
                end
            
                % ---------- 2) Fallback: robust whitespace + quotes ----------
                try
                    L = readlines(filepath);              % string array ทีละบรรทัด
            
                    % ลบ BOM (ถ้ามี) เฉพาะบรรทัดแรก
                    if ~isempty(L)
                        L(1) = replace(L(1), string(char([239 187 191])), "");  % UTF-8 BOM
                    end
            
                    % ตัดช่องว่างหัวท้าย และทิ้งบรรทัดว่าง/ missing
                    L = strtrim(L);
                    L = L(~ismissing(L) & strlength(L) > 0);
            
                    % ตัดเครื่องหมายคำพูดที่ครอบทั้งบรรทัด: "...." -> ....
                    L = regexprep(L, '^"(.*)"$', '$1');
            
                    % ทำช่องว่างให้เหลือช่องเดียว (กันเคสมีหลายช่อง/แท็บปน)
                    L = regexprep(L, '\s+', ' ');
            
                    % split ด้วยช่องว่างเดียว
                    tokens = regexp(L, ' ', 'split');
            
                    % หาบรรทัดอ้างอิง (มี token >= 2)
                    lens = cellfun(@numel, tokens);
                    anchorIdx = find(lens >= 2, 1, 'first');
                    if isempty(anchorIdx)
                        error("No valid data rows (after cleaning).");
                    end
                    ncols = lens(anchorIdx);
            
                    % อนุญาตทิ้งบรรทัดที่ไม่ตรง (เช่น ท้ายไฟล์ว่าง/เพี้ยน)
                    keep = (lens == ncols);
                    tokens = tokens(keep);
            
                    % แปลงเป็นตัวเลขและประกอบเป็นเมทริกซ์
                    rowsNum = cellfun(@(r) str2double(string(r)), tokens, 'UniformOutput', false);
                    M = vertcat(rowsNum{:});
            
                    if isempty(M) || any(isnan(M), 'all')
                        error("Non-numeric tokens found or empty content.");
                    end
                catch ME
                    M = [];
                    errmsg = ME.message;
                end
            end
            
            function [X,y] = splitXY_auto(app, M)
                % เดาว่า label อยู่คอลัมน์แรกหรือสุดท้ายแบบอัตโนมัติ
                c1 = M(:,1); cN = M(:,end);
                if isIntVector(app, cN) && numel(unique(cN)) >= 2 && ~isBinaryColumn(app, cN)
                    y = cN;  X = M(:,1:end-1);
                elseif isIntVector(app, c1) && numel(unique(c1)) >= 2 && ~isBinaryColumn(app, c1)
                    y = c1;  X = M(:,2:end);
                else
                    % fallback: เอาคอลัมน์สุดท้ายเป็น label
                    y = cN;  X = M(:,1:end-1);
                end
            end
            
            function tf = isBinaryColumn(~, v)
                v = v(~isnan(v));
                tf = ~isempty(v) && all(v==0 | v==1);
            end
            
            function tf = isIntVector(~, v)
                v = v(~isnan(v));
                tf = ~isempty(v) && all(mod(v,1)==0);
            end

            function setLampGray(app)
                if ~app.IsTraining
                    app.TrainLamp.Color = [0.5 0.5 0.5]; % เทา
                end
            end
    
            function setControlsEnabled(app, tf)
                state = tern(app, tf, 'on', 'off');
            
                % ซ้าย
                app.Threshold_1.Enable        = state;
                app.Sensitivity_1.Enable      = state;
                app.NumberofClauses_1.Enable  = state;
                app.NumberofStates_1.Enable   = state;
                app.NumberofFeatures_1.Enable = state;
                app.NumberofClasses_1.Enable  = state;
                app.Epochs_1.Enable           = state;
                app.ShowEpochs.Enable         = state;
            
                % ปุ่มไฟล์/รีเซ็ต/สวิตช์
                app.Switch2.Enable              = state;
                app.SelectedFileLabel.Enable    = state;
                app.SelectTestFileButton.Enable = state;
                app.ResetButton.Enable          = state;
            
                % ขวา
                if ~tf
                    % ระหว่างเทรนปิดทั้งหมด
                    app.Threshold_2.Enable='off';   app.ThresholdStep.Enable='off';
                    app.Sensitivity_2.Enable='off'; app.SensitivityStep.Enable='off';
                    app.ToLabel.Enable='off';       app.StepLabel.Enable='off';
                    app.ToLabel_2.Enable='off';     app.StepLabel_2.Enable='off';
                    app.SweepOrder.Enable='off';
                else
                    % หลังเทรน/ตอนเริ่ม เปิด/ปิดตามโหมด
                    if strcmp(app.Switch2.Value,'Sweep Mode')
                       app.SweepTarget.Enable = 'on';   % <-- เปิด dropdown กลับมา
                    else
                       app.SweepTarget.Enable = 'off';
                    end
                    % หลังเทรน/ตอนเริ่ม ใช้ตาม SweepTarget ปัจจุบัน
                    applySweepTargetEnable(app);
                end
                % ช่องนี้ต้องจางและแก้ไม่ได้เสมอ ไม่ว่า tf จะเป็นอะไร
                app.NumberofFeatures_1.Editable = 'off';
                app.NumberofFeatures_1.Enable   = 'off';
                
                app.NumberofClasses_1.Editable  = 'off';
                app.NumberofClasses_1.Enable    = 'off';
            end
    
            function out = tern(~,cond,a,b)
                if cond, out=a; else, out=b; end
            end
    
            function showTS(app, tf)
                vis = tern(app, tf, 'on', 'off');
                app.ThresholdState.Visible   = vis;
                app.SensitivityState.Visible = vis;
            end
            
            function setStateTS(app, T, S)
                if nargin>=2, app.ThresholdState.Text  = sprintf('Threshold : %.2f', T); end
                if nargin>=3, app.SensitivityState.Text = sprintf('Sensitivity : %.2f', S); end
            end

            function restoreUI(app)
                % ซ่อนสถานะ T,S
                showTS(app, false);
            
                % ปรับสถานะทั่วไป
                app.IsTraining          = false;
                app.TrainLabel.Text     = 'Train';
                app.TrainLamp.Color     = [0.5 0.5 0.5];
                app.StopButton.Enable   = 'off';
                app.TrainButton.Enable  = 'on';
            
                % เปิดสวิตช์/ปุ่มเลือกไฟล์/รีเซ็ต
                app.Switch2.Enable              = 'on';
                app.SelectedFileLabel.Enable    = 'on';
                app.SelectTestFileButton.Enable = 'on';
                app.ResetButton.Enable          = 'on';
            
                % เปิดช่องกรอกตามโหมด
                setControlsEnabled(app, true);
            
                drawnow;
            end

            function resetInputsKeepFiles(app)
                % เคลียร์ช่องตัวเลข/ช่วง/สเต็ปเท่านั้น
                app.Threshold_1.Value        = '';
                app.Sensitivity_1.Value      = '';
                app.NumberofClauses_1.Value  = '';
                app.NumberofStates_1.Value   = '';
                app.Epochs_1.Value           = '';
            
                app.Threshold_2.Value        = '';
                app.Sensitivity_2.Value      = '';
                app.ThresholdStep.Value      = '';
                app.SensitivityStep.Value    = '';
            
                % ไม่แตะต้อง: app.TrainingfilehasnotbeenselectedLabel, app.TestfilehasnotbeenselectedLabel, app.SelectedFile, X/Y train/val/test
                % จะไม่ไปล้างไฟล์ที่เลือกไว้
            end

            function acc = evaluateLikeClass(app, tm, X, y)
                % ทำให้ label เป็น 0-based ถ้าตอนโหลด dataset มาเป็น 1-based
                if min(y) == 1
                    y = y - 1;
                end
                num_samples = size(X, 1);
                correct = 0;
                for i = 1:num_samples
                    pred = tm.predict(X(i, :));
                    if pred == y(i)
                        correct = correct + 1;
                    end
                end
                acc = correct / num_samples;   % คืนค่าเป็น 0..1
            end

            function applySweepTargetEnable(app)
                % ใช้ปรับ enable/disable ฝั่งขวาตามโหมดปัจจุบัน
                if ~strcmp(app.Switch2.Value,'Sweep Mode')
                    setRight('off');  return;
                end
            
                switch app.SweepTarget.Value
                    case 'Threshold only'
                        setThresh('on');  setSens('off');  app.SweepOrder.Enable = 'off';
                    case 'Sensitivity only'
                        setThresh('off'); setSens('on');   app.SweepOrder.Enable = 'off';
                    otherwise % Both
                        setThresh('on');  setSens('on');   app.SweepOrder.Enable = 'on';
                end
            
                function setRight(state)
                    setThresh(state); setSens(state); app.SweepOrder.Enable = state;
                end
                function setThresh(state)
                    app.Threshold_2.Enable   = state;
                    app.ThresholdStep.Enable = state;
                    app.ToLabel.Enable       = state;   % label "To" ของ Threshold
                    app.StepLabel.Enable     = state;   % label "Step" ของ Threshold
                end
                function setSens(state)
                    app.Sensitivity_2.Enable   = state;
                    app.SensitivityStep.Enable = state;
                    app.ToLabel_2.Enable       = state; % label "To" ของ Sensitivity
                    app.StepLabel_2.Enable     = state; % label "Step" ของ Sensitivity
                end
            end
            
            function clearUnusedSweepFields(app)
                % ล้างค่าเฉพาะช่องที่ "ไม่ใช้" ตาม SweepTarget
                switch app.SweepTarget.Value
                    case 'Threshold only'
                        app.Sensitivity_2.Value   = '';
                        app.SensitivityStep.Value = '';
                    case 'Sensitivity only'
                        app.Threshold_2.Value     = '';
                        app.ThresholdStep.Value   = '';
                    otherwise
                        % Both -> ไม่ล้าง
                end
            end

            function fp = makeResultPngPath(app, tag)
                % tag: คำสั้นๆ เช่น 'sweep' หรือ 'fix'
                app.RunIdx = app.RunIdx + 1;
                tstamp = datestr(now,'yyyymmdd-HHMMSS');
                fname  = sprintf('run%04d_%s_%s.png', app.RunIdx, tag, tstamp);
                fp = fullfile(app.ResultsDir, fname);
            end
            
            function s = safeStr(~, x)
                % แทนที่อักขระต้องห้ามในชื่อไฟล์
                s = regexprep(string(x), '[:\\/<>|"?*]', '_');
                s = char(s);
            end
            
            function saveAxesPNG(~, ax, fullpath)
                % บันทึกแกนเป็น PNG ความละเอียดดี
                exportgraphics(ax, fullpath, 'Resolution', 220);
            end
            
            function openResultsFolder(app)
                % เปิดโฟลเดอร์ผลลัพธ์
                if ispc
                    winopen(app.ResultsDir);
                elseif ismac
                    system(['open ', '"', app.ResultsDir, '"']);
                else
                    system(['xdg-open ', '"', app.ResultsDir, '"']);
                end
            end

            function T = emptyHistoryTable(app)
                % ใช้ cell ของ char ตั้งแต่ต้น (ปลอดภัยสุดทุกเวอร์ชัน)
                vars = { ...
                    'Timestamp','Mode','SweepTarget','SweepOrder', ...
                    'Threshold_Start','Threshold_End','Threshold_Step', ...
                    'Sensitivity_Start','Sensitivity_End','Sensitivity_Step', ...
                    'Threshold_Used','Sensitivity_Used', ...
                    'BestThreshold','BestSensitivity', ...
                    'NumClauses','NumStates','NumFeatures','NumClasses', ...
                    'Epochs_Requested','Epochs_Completed','StoppedEarly', ...
                    'FinalTrainAcc','FinalTestAcc', ...
                    'TrainingFile','TestFile' ...
                };
            
                % กันผู้ใช้เผลอแก้ vars เป็น string array ในอนาคต
                if isstring(vars), vars = cellstr(vars); end
            
                % เผื่อชื่อแปลก ๆ ให้ valid และไม่ซ้ำ (ป้องกันไว้)
                vars = matlab.lang.makeValidName(vars);
                vars = matlab.lang.makeUniqueStrings(vars);
            
                T = cell2table(cell(0,numel(vars)), 'VariableNames', vars);
            end
            
            function appendHistory(app, row)
                % row: struct ที่ field ตรงกับชื่อคอลัมน์
                try
                    % เตรียมตารางบรรทัดใหม่ + โฟลเดอร์
                    T = struct2table(row, 'AsArray', true);
                    if ~isfolder(app.HistoryDir), mkdir(app.HistoryDir); end
            
                    % ---------- เขียน CSV (หลัก) ----------
                    try
                        if ~isfile(app.HistoryCsv)
                            writetable(app.emptyHistoryTable(), app.HistoryCsv);  % header
                        end
                        writetable(T, app.HistoryCsv, 'WriteMode', 'append');
                    catch eCsv
                        uialert(app.UIFigure, "Cannot write history (CSV): " + eCsv.message, ...
                            'History Error', 'Icon', 'warning');
                    end
            
                    % ---------- เขียน XLSX (เสริม, ไม่ให้ล้มแอป) ----------
                    try
                        % ใช้ temp แล้วย้ายทับ ลดปัญหาไฟล์ล็อกจาก OneDrive/Excel
                        tmpX = fullfile(tempdir, ['results_', char(java.util.UUID.randomUUID), '.xlsx']);
            
                        if ~isfile(app.HistoryXlsx)
                            % สร้างใหม่พร้อม header
                            writetable(app.emptyHistoryTable(), tmpX, 'FileType', 'spreadsheet');
                        else
                            % ทำสำเนาปัจจุบันไป temp ก่อน
                            copyfile(app.HistoryXlsx, tmpX, 'f');
                        end
            
                        % Append แถวใหม่
                        writetable(T, tmpX, 'FileType', 'spreadsheet', 'WriteMode', 'append');
            
                        % ย้ายทับปลายทาง (ถ้าปลายทางถูกล็อกจะ throw และเราจะไม่ล้มแอป)
                        movefile(tmpX, app.HistoryXlsx, 'f');
                    catch eXlsx
                            uialert(app.UIFigure, sprintf('Write XLSX failed:\n%s', eXlsx.message), ...
                                'History Warning', 'Icon','warning');
                    end
            
                catch ME
                    uialert(app.UIFigure, "Cannot write history: " + ME.message, ...
                        'History Error', 'Icon', 'warning');
                end
            end
            
            function showHistory(app)
                % อ่านไฟล์ xlsx ถ้ามี ไม่งั้น csv
                try
                    if isfile(app.HistoryXlsx)
                        T = readtable(app.HistoryXlsx);
                    elseif isfile(app.HistoryCsv)
                        T = readtable(app.HistoryCsv);
                    else
                        T = app.emptyHistoryTable();
                    end
                catch
                    T = app.emptyHistoryTable();
                end
            
                hf = uifigure('Name','Training History','Position',[100 100 1100 520]);
                hf.Color = app.UIFigure.Color;

                g  = uigridlayout(hf,[2 1]); g.RowHeight = {'1x',40}; g.Padding = [10 10 10 10];
            
                uit = uitable(g, 'Data', T, 'ColumnSortable', true, ...
                    'ColumnRearrangeable','on', 'RowStriping','on');
            
                hb = uigridlayout(g,[1 4]); hb.ColumnWidth = {120, 140, 110, '1x'};
                uibutton(hb,'Text','Open Folder', 'ButtonPushedFcn', @(~,~) app.openHistoryFolder());
                uibutton(hb,'Text','Refresh', 'ButtonPushedFcn', @(~,~) refreshHistory());

                function refreshHistory()
                    try
                        if isfile(app.HistoryXlsx)
                            Tnew = readtable(app.HistoryXlsx);
                        elseif isfile(app.HistoryCsv)
                            Tnew = readtable(app.HistoryCsv);
                        else
                            Tnew = app.emptyHistoryTable();
                        end
                    catch
                        % กรณีไฟล์ถูกเปิดทิ้งไว้/ล็อก หรืออ่านไม่ได้
                        Tnew = app.emptyHistoryTable();
                    end
                    set(uit, 'Data', Tnew);
                end
                uibutton(hb,'Text','Clear All',   'ButtonPushedFcn', @(~,~) app.clearHistory(uit));
                uilabel(hb,'Text', string(app.HistoryXlsx), 'HorizontalAlignment','right');
            end
            
            function openHistoryFolder(app)
                try
                    if ~isfolder(app.HistoryDir), return; end
                    if ispc
                        winopen(app.HistoryDir);
                    elseif ismac
                        system(['open ', '"', app.HistoryDir, '"']);
                    else
                        system(['xdg-open ', '"', app.HistoryDir, '"']);
                    end
                catch
                    % เงียบ ๆ ไม่ต้องทำอะไร หรือจะแจ้งเตือนก็ได้
                end
            end
            
            function clearHistory(app, uitHandle)
                selection = uiconfirm(app.UIFigure, ...
                    "Delete all history files?\n("+app.HistoryXlsx+" / "+app.HistoryCsv+")", ...
                    'Confirm Delete', 'Options',{'Cancel','Delete'}, 'DefaultOption',1,'Icon','warning');
                if ~strcmp(selection,'Delete'), return; end
                try
                    T0 = app.emptyHistoryTable();
                    writetable(T0, app.HistoryXlsx, 'FileType','spreadsheet');
                    writetable(T0, app.HistoryCsv);
                    if nargin>=2 && isvalid(uitHandle), uitHandle.Data = T0; end
                catch ME
                    uialert(app.UIFigure, "Clear failed: " + ME.message, 'History Error','Icon','error');
                end
            end
        end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TrainButton.Enable = 'off';
            app.StopButton.Enable  = 'off';
            app.ThresholdState.Visible  = 'off';
            app.SensitivityState.Visible = 'off';
            app.NumberofFeatures_1.Enable = 'off';
            app.NumberofClasses_1.Enable  = 'off';
            % อ่านอย่างเดียว และให้ช่องเป็นสีจาง
            app.NumberofFeatures_1.Editable = 'off';
            app.NumberofFeatures_1.Enable   = 'off';
            
            app.NumberofClasses_1.Editable  = 'off';
            app.NumberofClasses_1.Enable    = 'off';
            if isempty(app.ShowEpochs.Value), app.ShowEpochs.Value = '5'; end
        
            % ข้อความเริ่มต้นข้างปุ่มเลือกไฟล์
            app.TrainingfilehasnotbeenselectedLabel.Text = 'Training file has not been selected.';
            app.TestfilehasnotbeenselectedLabel.Text     = 'Test file has not been selected.';
        
            % ตั้งสถานะ enable เริ่มต้นตามโหมด
            setControlsEnabled(app, true);

            % ==== ตั้งโฟลเดอร์เก็บผลลัพธ์ ====
            try
                baseDir = fullfile(userpath, 'TsetlinGUI', 'results');  % เช่น ...\Documents\MATLAB\TsetlinGUI\results
            catch
                baseDir = fullfile(pwd, 'results');                      % เผื่อ userpath ใช้ไม่ได้
            end
            if ~exist(baseDir, 'dir'), mkdir(baseDir); end
            app.ResultsDir = baseDir;

            % ===== prepare history folder/files =====
            try
                if ~isfolder(app.HistoryDir), mkdir(app.HistoryDir); end
                if ~isfile(app.HistoryXlsx)
                    writetable(app.emptyHistoryTable(), app.HistoryXlsx, 'FileType','spreadsheet');
                end
                if ~isfile(app.HistoryCsv)
                    writetable(app.emptyHistoryTable(), app.HistoryCsv);
                end
            catch ME
                warning('TsetlinGUI:HistoryInit', ...
                        'History init failed: %s (id: %s)', ME.message, ME.identifier);
            end
        end

        % Button pushed function: SelectedFileLabel
        function SelectedFileLabelPushed(app, event)
            [file, path] = uigetfile({ ...
                '*.csv',  'CSV Files (*.csv)'; ...
                '*.xlsx', 'Excel Files (*.xlsx)'}, ...
                'Select a file');
        
            if isequal(file, 0)
                uialert(app.UIFigure, 'You have not selected a file.', 'Error', 'Icon', 'warning');
                return;
            end
        
            app.SelectedFile = fullfile(path, file);
        
            % โชว์ชื่อไฟล์
            app.TrainingfilehasnotbeenselectedLabel.Text = file;
        
            % อ่านไฟล์แบบยืดหยุ่น (CSV ปกติ, Excel, หรือไฟล์ที่เป็น "..." + ช่องว่าง)
            [data, errmsg] = readFlexibleFile(app, app.SelectedFile);
            if isempty(data)
                uialert(app.UIFigure, "Failed to read training file: " + errmsg, 'Error', 'Icon','error');
                app.TrainButton.Enable = 'off';
                return;
            end
        
            if size(data,2) < 2
                uialert(app.UIFigure,'The file must have at least 2 columns (features + label).','Invalid file format','Icon','error');
                app.TrainButton.Enable = 'off';
                return;
            end
        
            % แยก X/Y อัตโนมัติ (รองรับกรณี label อยู่หัว/ท้าย)
            [X, y] = splitXY_auto(app, data);
            app.XTrain = X;
            app.yTrain = y;
        
            % อัปเดตช่องแสดงจำนวนฟีเจอร์/คลาส (ถ้ามี)
            try
                app.NumberofFeatures_1.Value = num2str(size(X,2));
                app.NumberofClasses_1.Value  = num2str(numel(unique(y)));
            catch
                % ช่องนี้ไม่จำเป็นต้องมี
            end
        
            app.TrainButton.Enable = 'on';
            disp("Training file loaded successfully.");
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % ==== ยกเลิกการเทรนที่อาจกำลังรันอยู่ ====
            app.stopFlag   = true;
            app.IsTraining = false;
            drawnow;
        
            % ==== เคลียร์ค่าพารามิเตอร์ฝั่งซ้าย ====
            app.Threshold_1.Value        = '';
            app.Sensitivity_1.Value      = '';
            app.NumberofClauses_1.Value  = '';
            app.NumberofStates_1.Value   = '';
            app.NumberofFeatures_1.Value = '';
            app.NumberofClasses_1.Value  = '';
            app.Epochs_1.Value           = '';
        
            % ==== เคลียร์ค่าพารามิเตอร์ฝั่งขวา (ช่วง/สเต็ป) ====
            app.Threshold_2.Value        = '';
            app.Sensitivity_2.Value      = '';
            app.ThresholdStep.Value      = '';
            app.SensitivityStep.Value    = '';
        
            % ==== เคลียร์ชื่อไฟล์ที่แสดง ====
            app.TrainingfilehasnotbeenselectedLabel.Text = 'Training file has not been selected.';
            app.TestfilehasnotbeenselectedLabel.Text     = 'Test file has not been selected.';
        
            % ==== ล้างข้อมูล Train/Val/Test และไฟล์ที่เลือก ====
            app.SelectedFile = '';
            app.XTrain = []; app.yTrain = [];
            app.XTest  = []; app.yTest  = [];
        
            % ==== ล้างโมเดลที่เทรนไว้ ====
            app.TrainedModel = [];
        
            % ==== รีเซ็ตกราฟ ====
            cla(app.UIAxes, 'reset');          % <-- รีเซ็ตแกนให้หมด (สำคัญ)
            hold(app.UIAxes, 'on');
            grid(app.UIAxes, 'on');
            
            % คืนค่าป้ายกำกับ/ขอบเขต/ทิคให้เป็นอัตโนมัติ
            title(app.UIAxes, '');
            xlabel(app.UIAxes, 'Epochs');
            ylabel(app.UIAxes, 'Accuracy');
            
            app.UIAxes.XLimMode = 'auto';
            app.UIAxes.YLimMode = 'auto';
            app.UIAxes.XTickMode = 'auto';
            app.UIAxes.YTickMode = 'auto';
            app.UIAxes.XTickLabelMode = 'auto';
            app.UIAxes.XTickLabelRotation = 0;
            
            % ล้าง handle เส้นเก่าออก
            app.hTrain = matlab.graphics.chart.primitive.Line.empty;
            
            drawnow;
        
            % ==== ดับไฟสถานะ ====
            app.TrainLamp.Color = [0.5 0.5 0.5];
        
            % ==== ปิดปุ่ม Train จนกว่าจะเลือกไฟล์ใหม่ ====
            app.TrainButton.Enable = 'off';
            app.StopButton.Enable  = 'off';

            % เปิด/ปิดช่องตามโหมด
            setControlsEnabled(app, true);

            % รีเซ็ตข้อความสถานะ
            showTS(app, false);
            app.ThresholdState.Text   = 'Threshold :';
            app.SensitivityState.Text = 'Sensitivity :';
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            % ต้องมี Training file เสมอ
            if isempty(app.SelectedFile) || isempty(app.XTrain) || isempty(app.yTrain)
                app.TrainingfilehasnotbeenselectedLabel.Text = 'Training file has not been selected.';
                uialert(app.UIFigure,'Training file has not been selected.','Missing file','Icon','warning');
                return;
            end
        
            % Test file ไม่บังคับ ถ้าไม่มีให้โชว์ข้อความที่ Label
            if isempty(app.XTest) || isempty(app.yTest)
                app.TestfilehasnotbeenselectedLabel.Text = 'Test file has not been selected.';
            end
        
            % === Fix random seed ===
            rng('shuffle');     % สุ่ม seed ใหม่ทุกครั้งที่กด Train
        
            % === เตรียมข้อมูลเริ่มต้น ===
            X_train = app.XTrain;
            y_train = app.yTrain;
        
            % === กรอง NaN ใน y_train ===
            valid_idx = ~isnan(y_train);
            X_train = X_train(valid_idx,:);
            y_train = y_train(valid_idx);
        
            % === ทำ label ให้เป็น 0-based และเป็นคอลัมน์ ===
            y_train = toZeroBased(y_train);
            if isrow(y_train), y_train = y_train(:); end
        
            % === เตรียม y_test (0-based) ถ้ามี ===
            y_test0 = [];
            if ~isempty(app.yTest)
                y_test0 = toZeroBased(app.yTest);
                if isrow(y_test0), y_test0 = y_test0(:); end
            end
        
            % === จำนวนคลาสจากข้อมูลจริง ===
            pool = y_train;
            if ~isempty(y_test0), pool = [pool; y_test0]; end
            NumClassesEff = max(pool) + 1;
            if NumClassesEff < 2
                uialert(app.UIFigure,'The number of classes is less than 2. Please check the label.','Error','Icon','error');
                app.restoreUI();
                return;
            end
        
            % === ตรวจค่าจาก UI ที่จำเป็น (ซ้าย) ===
            requiredLeft = { ...
                app.Threshold_1.Value, app.Sensitivity_1.Value, ...
                app.NumberofClauses_1.Value, app.NumberofStates_1.Value, ...
                app.Epochs_1.Value };
        
            % อ่าน SweepTarget (fallback = Both)
            sweepTarget = 'Both';
            if isprop(app,'SweepTarget') && ~isempty(app.SweepTarget.Value)
                sweepTarget = char(app.SweepTarget.Value);
            end
        
            % === ตรวจค่าฝั่งขวาตาม SweepTarget ===
            if strcmp(app.Switch2.Value, "Sweep Mode")
                switch sweepTarget
                    case 'Threshold only'
                        requiredRight = { app.Threshold_2.Value, app.ThresholdStep.Value };
                    case 'Sensitivity only'
                        requiredRight = { app.Sensitivity_2.Value, app.SensitivityStep.Value };
                    otherwise % 'Both'
                        requiredRight = { ...
                            app.Threshold_2.Value, app.Sensitivity_2.Value, ...
                            app.ThresholdStep.Value, app.SensitivityStep.Value };
                end
                if any(cellfun(@isempty, requiredLeft)) || any(cellfun(@isempty, requiredRight))
                    uialert(app.UIFigure,'Please fill in the parameters required for the selected sweep before Train.','Error','Icon','error');
                    return;
                end
            else
                if any(cellfun(@isempty, requiredLeft))
                    uialert(app.UIFigure,'Please fill in all parameters before Train.','Error','Icon','error');
                    return;
                end
            end
        
            % === อ่านพารามิเตอร์ตามโหมด ===
            if strcmp(app.Switch2.Value, "Sweep Mode")
                % ค่าคงที่ร่วม
                NumClauses  = str2double(app.NumberofClauses_1.Value);
                NumStates   = str2double(app.NumberofStates_1.Value);
                NumFeatures = size(X_train, 2);
                Epochs      = str2double(app.Epochs_1.Value);
        
                % อ่านค่าจาก UI (เป็นตัวเลข)
                T1   = str2double(app.Threshold_1.Value);
                T2   = str2double(app.Threshold_2.Value);
                Tst  = str2double(app.ThresholdStep.Value);
                S1   = str2double(app.Sensitivity_1.Value);
                S2   = str2double(app.Sensitivity_2.Value);
                Sst  = str2double(app.SensitivityStep.Value);
        
                % ตรวจและสร้างช่วงตาม SweepTarget
                switch sweepTarget
                    case 'Threshold only'
                        Sensitivity = str2double(getParam(app.Sensitivity_1.Value, app.Sensitivity_2.Value));
                        if any(isnan([T1,T2,Tst,Sensitivity])) || Tst <= 0
                            uialert(app.UIFigure,'Invalid Threshold range/step or fixed Sensitivity.','Error','Icon','error'); return;
                        end
                        if T1 > T2
                            uialert(app.UIFigure,'Threshold Start must not be greater than the End value.','Error','Icon','error'); return;
                        end
                        TRange = T1:Tst:T2;
                        SRange = Sensitivity;    % ค่าคงที่
        
                    case 'Sensitivity only'
                        Threshold = str2double(getParam(app.Threshold_1.Value, app.Threshold_2.Value));
                        if any(isnan([S1,S2,Sst,Threshold])) || Sst <= 0
                            uialert(app.UIFigure,'Invalid Sensitivity range/step or fixed Threshold.','Error','Icon','error'); return;
                        end
                        if S1 > S2
                            uialert(app.UIFigure,'Sensitivity Start must not be greater than the End value.','Error','Icon','error'); return;
                        end
                        SRange = S1:Sst:S2;
                        TRange = Threshold;      % ค่าคงที่
        
                    otherwise % Both
                        if any(isnan([T1,T2,Tst,S1,S2,Sst])) || Tst <= 0 || Sst <= 0
                            uialert(app.UIFigure,'The range/step value is invalid.','Error','Icon','error'); return;
                        end
                        if T1 > T2 || S1 > S2
                            uialert(app.UIFigure,'The Start value must not be greater than the End value.','Error','Icon','error'); return;
                        end
                        TRange = T1:Tst:T2;
                        SRange = S1:Sst:S2;
                end
        
            else
                % ---------- FIX MODE ----------
                Threshold   = str2double(getParam(app.Threshold_1.Value, app.Threshold_2.Value));
                Sensitivity = str2double(getParam(app.Sensitivity_1.Value, app.Sensitivity_2.Value));
                NumClauses  = str2double(app.NumberofClauses_1.Value);
                NumStates   = str2double(app.NumberofStates_1.Value);
                NumFeatures = size(X_train, 2);
                Epochs      = str2double(app.Epochs_1.Value);
            end
        
            % === ตรวจความสมเหตุสมผล ===
            if isnan(Epochs) || Epochs < 1 || mod(Epochs,1) ~= 0
                uialert(app.UIFigure,'Epochs must be a positive integer greater than 0.','Error','Icon','error');
                return;
            end
            if isnan(NumClauses) || NumClauses<1 || mod(NumClauses,1)~=0
                uialert(app.UIFigure,'Number of Clauses must be a positive integer.','Error','Icon','error'); return;
            end

            if isnan(NumStates) || NumStates<1 || mod(NumStates,1)~=0
                uialert(app.UIFigure,'Number of States must be a positive integer.','Error','Icon','error'); return;
            end
        
            % === อ่านค่า ShowEpochs ===
            if ~isprop(app,'ShowEpochs') || isempty(strtrim(string(app.ShowEpochs.Value)))
                uialert(app.UIFigure, 'Please enter the value "Show Epochs".', 'Invalid Show Epochs', 'Icon','error');
                return;
            end
            showStep = str2double(app.ShowEpochs.Value);
            if isnan(showStep) || mod(showStep,1)~=0 || showStep < 1 || showStep > Epochs
                uialert(app.UIFigure, sprintf('The "Show Epochs" value must be an integer from 1 to %d', Epochs), ...
                    'Invalid Show Epochs','Icon','error');
                return;
            end
            showStep = floor(showStep);
        
            % === เตรียมกราฟ ===
            cla(app.UIAxes);
            cla(app.UIAxes,'reset');     % สำคัญ: รีเซ็ตชนิดแกนกลับเป็น numeric
            hold(app.UIAxes, 'on');
            xlabel(app.UIAxes,'Epochs'); ylabel(app.UIAxes,'Accuracy');
            title(app.UIAxes,'Training Accuracy'); grid(app.UIAxes,'on');
            xlim(app.UIAxes,[0 Epochs]); ylim(app.UIAxes,[0 1]);
            app.hTrain = plot(app.UIAxes, NaN, NaN, '-', 'LineWidth', 2);
            applyEpochTicks(app.UIAxes, Epochs)
            drawnow;
        
            % === เปิดไฟสถานะ + ปรับปุ่ม ===
            app.TrainLamp.Color = [1 1 0];
            app.TrainLabel.Text = 'Training';
            app.StopButton.Enable  = 'on';
            app.TrainButton.Enable = 'off';
            app.IsTraining         = true;
        
            % ล็อคการเปลี่ยนโหมด/เลือกไฟล์/รีเซ็ต + ฟิลด์ต่าง ๆ
            app.Switch2.Enable = 'off';
            app.SelectedFileLabel.Enable    = 'off';
            app.SelectTestFileButton.Enable = 'off';
            app.ResetButton.Enable          = 'off';
            app.ShowEpochs.Enable           = 'off';
            app.SweepTarget.Enable          = 'off';
            app.HistoryButton.Enable        = 'off';
            setControlsEnabled(app, false);
        
            % แสดงค่า T,S
            showTS(app, true);
            if strcmp(app.Switch2.Value,'Sweep Mode')
                app.ThresholdState.Text   = 'T = -';
                app.SensitivityState.Text = 'S = -';
            else
                app.ThresholdState.Text   = sprintf('T = %.2f', Threshold);
                app.SensitivityState.Text = sprintf('S = %.2f', Sensitivity);
            end
            drawnow limitrate;
        
            % === Train (try/catch เพื่อคืน UI เสมอ) ===
            try
                app.stopFlag = false;
                stopNotice = '';
                wasStopped = false;
                epStopped = 0;
        
                % ตัวแปรสำหรับ SWEEP
                bestScore     = -inf;
                bestModel     = [];
                bestT         = NaN;
                bestS         = NaN;
                bestAccCurve  = [];
                bestAccEpochs = [];
        
                % ---- เก็บผลทุกคู่ไว้ทำกราฟสรุป X=(T,S), Y=Accuracy ----
                sweepTs = [];
                sweepSs = [];
                sweepAcc = [];
                sweepLabels = strings(0,1);
        
                if strcmp(app.Switch2.Value,'Sweep Mode')
                    % ---------- SWEEP MODE ----------
                    order = app.SweepOrder.Value;   % 'T→S' หรือ 'S→T'
                    outerBreak = false;
        
                    switch order
                        case 'T→S'     % S วงนอก
                            for S = SRange
                                if app.stopFlag, outerBreak = true; break; end
                                for T = TRange
                                    if trainOnePair(T,S), outerBreak = true; break; end
                                end
                                if outerBreak, break; end
                            end
                        case 'S→T'     % T วงนอก
                            for T = TRange
                                if app.stopFlag, outerBreak = true; break; end
                                for S = SRange
                                    if trainOnePair(T,S), outerBreak = true; break; end
                                end
                                if outerBreak, break; end
                            end
                    end
        
                    % ต้องมีโมเดลที่ดีที่สุด มิฉะนั้น tm จะไม่ถูกกำหนด
                    if isempty(bestModel)
                        app.restoreUI();
                        uialert(app.UIFigure,'Training was canceled before any model completed.','Info','Icon','warning');
                        return;
                    end
        
                    % <<< สำคัญ: กำหนด tm จากผลลัพธ์ที่ดีที่สุด >>>
                    tm = bestModel;
                    Threshold   = bestT;
                    Sensitivity = bestS;
        
                    % ===== วาด "กราฟสรุปหลังจบ Sweep" =====
                    cla(app.UIAxes,'reset'); hold(app.UIAxes,'on'); grid(app.UIAxes,'on');
                    ylabel(app.UIAxes,'Accuracy'); ylim(app.UIAxes,[0 1]);
        
                    % ตั้งชื่อกราฟ
                    if strcmp(sweepTarget,'Both')
                        ttl = sprintf('Sweep Results: [T : %g to %g (Step: %g)] , [S : %g to %g (Step: %g)]', T1, T2, Tst, S1, S2, Sst);
                    elseif strcmp(sweepTarget,'Threshold only')
                        ttl = sprintf('Sweep Results: [T : %g to %g (Step: %g)] , [S fixed = %g]', T1, T2,  Tst, bestS);
                    else
                        ttl = sprintf('Sweep Results: [S : %g to %g (Step: %g)] , [T fixed = %g]', S1, S2, Sst, bestT);
                    end
                    title(app.UIAxes, ttl);
        
                    % ใช้แกน x เป็นดัชนี 1..N
                    N = numel(sweepAcc);
                    x = 1:N;
                    app.hTrain = plot(app.UIAxes, x, sweepAcc, '-', 'LineWidth', 2);
                    
                    if N == 1
                        xlim(app.UIAxes,[1 2]);
                    else
                        xlim(app.UIAxes,[1 N]);
                    end
                    
                    % --- ตั้งค่า tick ---
                    if N > 100
                        tickIdx = 1:10:N;     % กรณีมีมากกว่า 100 → ห่างทุก 10 จุด
                    elseif N > 20
                        tickIdx = 1:5:N;      % กรณี 21–100 → ห่างทุก 5 จุด
                    else
                        tickIdx = 1:N;        % กรณี ≤ 20 → แสดงทุกค่า
                    end
                    app.UIAxes.XTick = tickIdx;
                    app.UIAxes.XTickLabel = cellstr(sweepLabels(tickIdx));
                    app.UIAxes.XTickLabelRotation = 45;
                    
                    % --- กำหนด datatip ให้เส้นหลัก ---
                    row1 = dataTipTextRow('T,S', cellstr(sweepLabels));
                    row2 = dataTipTextRow('Accuracy', sweepAcc);
                    app.hTrain.DataTipTemplate.DataTipRows = [row1 row2];
                    
                    % --- หาและวาดจุดที่ดีที่สุดก่อน ---
                    [~, idxBestPoint] = max(sweepAcc);
                    hBest = plot(app.UIAxes, x(idxBestPoint), sweepAcc(idxBestPoint), 'o', ...
                        'MarkerSize', 9, 'LineWidth', 2.2, 'MarkerFaceColor', 'none', ...
                        'MarkerEdgeColor', [1 0.5 0]);
                    
                    % --- ตั้ง datatip ให้จุดไฮไลต์ ---
                    row1b = dataTipTextRow('T,S',   cellstr(sweepLabels(idxBestPoint)));
                    row2b = dataTipTextRow('Accuracy', sweepAcc(idxBestPoint));
                    hBest.DataTipTemplate.DataTipRows = [row1b row2b];
        
                    % xlabel ตามโหมด (เป็นตัวเลขล้วน)
                    if strcmp(sweepTarget,'Threshold only')
                        xlabel(app.UIAxes,'T');
                    elseif strcmp(sweepTarget,'Sensitivity only')
                        xlabel(app.UIAxes,'S');
                    else
                        xlabel(app.UIAxes,'T,S');
                    end
        
                else
                    % ---------- FIX MODE ----------
                    tm = TsetlinMachine(NumClassesEff, NumClauses, NumFeatures, NumStates, Sensitivity, Threshold, 0);
        
                    % เก็บค่า T,S ที่ใช้จริง (สำหรับรายงานตอนกด Stop)
                    app.CurrentT = Threshold;
                    app.CurrentS = Sensitivity;
        
                    accTrainSeries = nan(1, Epochs);
                    for ep = 1:Epochs
                        if app.stopFlag
                            wasStopped = true;
                            epStopped  = ep;
                            stopNotice = sprintf('⚠ Training stopped at Epochs : %d (Threshold = %.2f, Sensitivity = %.2f)\n', ...
                                                 epStopped, Threshold, Sensitivity);
                            break;
                        end
                        for i = 1:size(X_train,1)
                            target_class = y_train(i) + 1;
                            tm = tm.update(X_train(i,:), target_class);
                        end
                        accTrainSeries(ep) = tm.evaluate(X_train, y_train);
                        if mod(ep,showStep)==0 || ep==1 || ep==Epochs
                            xx = 1:showStep:ep; if xx(end)~=ep, xx=[xx ep]; end
                            set(app.hTrain,'XData',xx,'YData',accTrainSeries(xx));
                            drawnow limitrate;
                        end
                    end
                end
        
                if ~wasStopped, epStopped = Epochs; end
        
                % ตอนนี้ tm ถูกกำหนดแน่นอนแล้ว
                final_train_acc = tm.evaluate(X_train, y_train) * 100;
                if ~isempty(app.XTest) && ~isempty(app.yTest)
                    acc_test = tm.evaluate(app.XTest, y_test0) * 100;
                else
                    acc_test = NaN;
                end
                
                % ---------- ชื่อไฟล์ไว้ใช้ทั้ง History และ Popup ----------
                if ~isempty(app.SelectedFile)
                    [~, trainBase, trainExt] = fileparts(app.SelectedFile);
                    trainFileShown = [trainBase trainExt];
                else
                    trainFileShown = 'No training file selected';
                end
                if ~isempty(app.XTest) && ~isempty(app.yTest)
                    testFileShown = char(app.TestfilehasnotbeenselectedLabel.Text);
                else
                    testFileShown = 'No test file selected';
                end
                
                % ---------- เตรียมค่าที่จะเขียน history ----------
                isSweep = strcmp(app.Switch2.Value,"Sweep Mode");
                modeStr = tern(app, isSweep, "Sweep", "Fix");
                tgStr   = tern(app, isSweep, string(sweepTarget), "");
                
                % หลีกเลี่ยงการ evaluate 'order' ล่วงหน้า (เพราะมีเฉพาะตอนสวีป)
                ordStr = "";
                if isSweep
                    ordStr = string(order);
                end
                
                % ถ้าเป็น Fix → ไม่มีช่วง ให้ใส่ NaN
                ThStart = NaN; ThEnd = NaN; ThStep = NaN;
                SsStart = NaN; SsEnd = NaN; SsStep = NaN;
                if isSweep
                    if exist('T1','var'),  ThStart = T1;  end
                    if exist('T2','var'),  ThEnd   = T2;  end
                    if exist('Tst','var'), ThStep  = Tst; end
                    if exist('S1','var'),  SsStart = S1;  end
                    if exist('S2','var'),  SsEnd   = S2;  end
                    if exist('Sst','var'), SsStep  = Sst; end
                end
                
                % ใช้ค่าที่ model สุดท้ายใช้จริง
                ThUsed = tern(app, isSweep, bestT, Threshold);
                SsUsed = tern(app, isSweep, bestS, Sensitivity);
                
                row = struct( ...
                    "Timestamp", datestr(now,'yyyy-mm-dd HH:MM:SS'), ...
                    "Mode", modeStr, ...
                    "SweepTarget", tgStr, ...
                    "SweepOrder",  ordStr, ...
                    "Threshold_Start", ThStart, ...
                    "Threshold_End",   ThEnd, ...
                    "Threshold_Step",  ThStep, ...
                    "Sensitivity_Start", SsStart, ...
                    "Sensitivity_End",   SsEnd, ...
                    "Sensitivity_Step",  SsStep, ...
                    "Threshold_Used", ThUsed, ...
                    "Sensitivity_Used", SsUsed, ...
                    "BestThreshold", tern(app,isSweep,bestT,NaN), ...
                    "BestSensitivity", tern(app,isSweep,bestS,NaN), ...
                    "NumClauses",  NumClauses, ...
                    "NumStates",   NumStates, ...
                    "NumFeatures", size(X_train,2), ...
                    "NumClasses",  NumClassesEff, ...
                    "Epochs_Requested", Epochs, ...
                    "Epochs_Completed", epStopped, ...
                    "StoppedEarly", tern(app, wasStopped, "Yes", "No"), ...
                    "FinalTrainAcc", final_train_acc, ...
                    "FinalTestAcc",  iif(~isnan(acc_test), acc_test, NaN), ...
                    "TrainingFile", safeText(trainFileShown), ...
                    "TestFile",     safeText(testFileShown) ...
                );
                
                % เขียนลงไฟล์
                app.appendHistory(row);
        
                % ชื่อไฟล์ไว้โชว์ใน popup
                if ~isempty(app.SelectedFile)
                    [~, trainBase, trainExt] = fileparts(app.SelectedFile);
                    trainFileShown = [trainBase trainExt];
                else
                    trainFileShown = 'No training file selected';
                end
                if ~isempty(app.XTest) && ~isempty(app.yTest)
                    testFileShown = safeText(app.TestfilehasnotbeenselectedLabel.Text);
                else
                    testFileShown = 'No test file selected';
                end
        
                % === คืน UI ===
                app.restoreUI();
                if app.PendingClose || ~isvalid(app.UIFigure)
                    if ~isempty(app.LampTimer) && isvalid(app.LampTimer)
                        stop(app.LampTimer); delete(app.LampTimer);
                    end
                    delete(app);
                    return;
                end
                if ~wasStopped
                    app.TrainLamp.Color = [0 1 0];   % เขียวชั่วคราว
                    if ~isempty(app.LampTimer) && isvalid(app.LampTimer)
                        stop(app.LampTimer); delete(app.LampTimer);
                    end
                    app.LampTimer = timer('StartDelay',5,'ExecutionMode','singleShot','TimerFcn',@(~,~) setLampGray(app));
                    start(app.LampTimer);
                end
        
                % แสดงค่า T,S
                if ~strcmp(app.Switch2.Value,'Sweep Mode')
                    app.ThresholdState.Text   = sprintf('T = %.2f', Threshold);
                    app.SensitivityState.Text = sprintf('S = %.2f', Sensitivity);
                else
                    app.ThresholdState.Text   = sprintf('T = %.2f', bestT);
                    app.SensitivityState.Text = sprintf('S = %.2f', bestS);
                end
        
                % ==== สร้างบรรทัดบอกจำนวน epoch ====
                if wasStopped
                    epochLine2 = sprintf(['Epochs : %d\n' ...
                                          '⚠ Training stopped at Epochs : %d (T = %.4g, S = %.4g)'], ...
                                          Epochs, epStopped, app.CurrentT, app.CurrentS);
                else
                    epochLine2 = sprintf('Epochs: %d', Epochs);
                end
        
                % === สร้างข้อความพารามิเตอร์ ===
                if strcmp(app.Switch2.Value, "Sweep Mode")
                    switch sweepTarget
                        case 'Threshold only'
                            paramStr = sprintf([ ...
                                'Sweep Mode : Threshold only\n' ...
                                'Threshold Range: %s to %s (Step: %s)\n' ...
                                'Sensitivity (fixed): %s\n' ...
                                'Clauses: %d\nStates: %d\nFeatures: %d\nClasses: %d\n' ...
                                '%s\n' ...
                                '\nBest Threshold: %g , Best Sensitivity: %g\n' ...
                                '\nFinal Train Accuracy: %.2f%%\n%s\n' ...
                                '\n' ...
                                'Training File: %s\n' ...
                                'Test File: %s' ], ...
                                app.Threshold_1.Value, app.Threshold_2.Value, app.ThresholdStep.Value, ...
                                app.Sensitivity_1.Value, ...
                                NumClauses, NumStates, NumFeatures, NumClassesEff, ...
                                epochLine2, ...
                                bestT, bestS, ...
                                final_train_acc, ...
                                iif(~isnan(acc_test), sprintf('Final Test Accuracy: %.2f%%', acc_test), ...
                                   'Final Test Accuracy: No file selected for Test'), ...
                                trainFileShown, testFileShown);
        
                        case 'Sensitivity only'
                            paramStr = sprintf([ ...
                                'Sweep Mode : Sensitivity only\n' ...
                                'Threshold (fixed): %s\n' ...
                                'Sensitivity Range: %s to %s (Step: %s)\n' ...
                                'Clauses: %d\nStates: %d\nFeatures: %d\nClasses: %d\n' ...
                                '%s' ...
                                '\n' ...
                                '\nBest Sensitivity: %g , Best Threshold: %g\n' ...
                                '\nFinal Train Accuracy: %.2f%%\n%s\n' ...
                                '\n' ...
                                'Training File: %s\n' ...
                                'Test File: %s' ], ...
                                app.Threshold_1.Value, ...
                                app.Sensitivity_1.Value, app.Sensitivity_2.Value, app.SensitivityStep.Value, ...
                                NumClauses, NumStates, NumFeatures, NumClassesEff, ...
                                epochLine2, ...
                                bestS, bestT, ...
                                final_train_acc, ...
                                iif(~isnan(acc_test), sprintf('Final Test Accuracy: %.2f%%', acc_test), ...
                                   'Final Test Accuracy: No file selected for Test'), ...
                                trainFileShown, testFileShown);
        
                        otherwise % 'Both'
                            paramStr = sprintf([ ...
                                'Sweep Mode : Both\n' ...
                                'Threshold Range: %s to %s (Step: %s)\n' ...
                                'Sensitivity Range: %s to %s (Step: %s)\n' ...
                                'Clauses: %d\nStates: %d\nFeatures: %d\nClasses: %d\n' ...
                                '%s' ...
                                '\n' ...
                                '\nBest Threshold: %g , Best Sensitivity: %g\n' ...
                                '\nFinal Train Accuracy: %.2f%%\n%s\n' ...
                                '\n' ...
                                'Training File: %s\n' ...
                                'Test File: %s' ], ...
                                app.Threshold_1.Value, app.Threshold_2.Value, app.ThresholdStep.Value, ...
                                app.Sensitivity_1.Value, app.Sensitivity_2.Value, app.SensitivityStep.Value, ...
                                NumClauses, NumStates, NumFeatures, NumClassesEff, ...
                                epochLine2, ...
                                bestT, bestS, ...
                                final_train_acc, ...
                                iif(~isnan(acc_test), sprintf('Final Test Accuracy: %.2f%%', acc_test), ...
                                   'Final Test Accuracy: No file selected for Test'), ...
                                trainFileShown, testFileShown);
                    end
                else
                    % ---------- FIX MODE ----------
                    paramStr = sprintf([ ...
                        'Fix Mode :\n' ...
                        'Threshold: %s\nSensitivity: %s\n' ...
                        'Clauses: %s\nStates: %s\n' ...
                        'Features: %d\nClasses: %d\n' ...
                        '%s\n' ...
                        '\n' ...
                        'Final Train Accuracy: %.2f%%\n%s\n' ...
                        '\n' ...
                        '\nTraining File: %s\n' ...
                        'Test File: %s' ], ...
                        app.Threshold_1.Value, app.Sensitivity_1.Value, ...
                        app.NumberofClauses_1.Value, app.NumberofStates_1.Value, ...
                        NumFeatures, NumClassesEff, ...
                        epochLine2, ...
                        final_train_acc, ...
                        iif(~isnan(acc_test), sprintf('Final Test Accuracy: %.2f%%', acc_test), ...
                           'Final Test Accuracy: No file selected for Test'), ...
                        trainFileShown, testFileShown);
                end
        
                % ================== POPUP : กราฟจริง + ลิงก์ซูม ==================
                figW = 757; figH = 630;
                M = 28; gap1 = 5; gap2 = 20; btnGap = 24;
                axH = 280; axW = figW - 2*M; axX = M; axY = figH - M - axH;
                msgW = 320; msgH = 250;
                cx = @(w) (figW - w)/2;
                msgX = cx(msgW); msgY = axY - gap1 - msgH;
                btnW = 140; btnH = 34; btnY = msgY - gap2 - btnH;
                leftX  = cx(2*btnW + btnGap);
                rightX = leftX + btnW + btnGap;
        
                popupFig = uifigure('Name','Training Result', 'Position',[100 100 figW figH]);
                popupFig.Color = app.UIFigure.Color;
                ax = uiaxes(popupFig, 'Position', [axX axY axW axH]);
        
                % คัดลอกเส้นกราฟทั้งหมดจากแกนหลัก
                copyobj(allchild(app.UIAxes), ax);
        
                % คัดลอกการตั้งค่าแกน
                ax.XLim = app.UIAxes.XLim;   ax.YLim = app.UIAxes.YLim;
                ax.XTick = app.UIAxes.XTick; ax.YTick = app.UIAxes.YTick;
                ax.XTickLabel = app.UIAxes.XTickLabel;
                ax.XTickLabelRotation = app.UIAxes.XTickLabelRotation;
                ax.Title.String = app.UIAxes.Title.String;
                ax.XLabel.String = app.UIAxes.XLabel.String;
                ax.YLabel.String = app.UIAxes.YLabel.String;
                grid(ax, 'on');

                if isSweep
                    N = numel(sweepAcc);
                    hLines = findobj(ax,'Type','line');
                
                    % เส้นหลัก
                    hMain = [];
                    for k = 1:numel(hLines)
                        if numel(hLines(k).YData) == N
                            hMain = hLines(k); break;
                        end
                    end
                    if ~isempty(hMain)
                        row1 = dataTipTextRow('T,S', cellstr(sweepLabels));
                        row2 = dataTipTextRow('Accuracy', sweepAcc);
                        hMain.DataTipTemplate.DataTipRows = [row1 row2];
                    end
                
                    % จุดไฮไลต์ (มักจะเป็น line ที่มีจุดเดียว และ Marker = 'o')
                    hBest2 = [];
                    for k = 1:numel(hLines)
                        if numel(hLines(k).YData) == 1 && strcmp(hLines(k).Marker,'o')
                            hBest2 = hLines(k); break;
                        end
                    end
                    if ~isempty(hBest2)
                        row1b = dataTipTextRow('T,S', cellstr(sweepLabels(idxBestPoint)));
                        row2b = dataTipTextRow('Accuracy', sweepAcc(idxBestPoint));
                        hBest2.DataTipTemplate.DataTipRows = [row1b row2b];
                    end
                
                    ax.XLabel.String = 'T,S';
                end
        
                % ให้ซูม/แพนเหมือนกันทั้งสองฝั่ง
                enableDefaultInteractivity(ax);
        
                % ข้อความพารามิเตอร์ + ปุ่ม
                uitextarea(popupFig, 'Value', splitlines(paramStr), ...
                    'Position', [msgX msgY msgW msgH], 'Editable', 'off');
        
                uibutton(popupFig,'push','Text','Save Graph', ...
                    'Position',[leftX btnY btnW btnH], 'ButtonPushedFcn', @(~,~) saveGraphPopup(ax));
                uibutton(popupFig,'push','Text','Save Parameter', ...
                    'Position',[rightX btnY btnW btnH], 'ButtonPushedFcn', @(~,~) saveParams());
        
                app.TrainedModel = tm;
        
            catch ME
                app.restoreUI();
        
                % ถ้าผู้ใช้กดปิดระหว่างเทรน -> ปิดจริงเลย ไม่ต้อง alert/popup
                if app.PendingClose || ~isvalid(app.UIFigure)
                    if ~isempty(app.LampTimer) && isvalid(app.LampTimer)
                        stop(app.LampTimer); delete(app.LampTimer);
                    end
                    delete(app);
                    return;
                end
        
                % ปกติ (ไม่ได้ปิดหน้าต่าง) ค่อยแจ้ง error
                uialert(app.UIFigure, "Error while training: " + ME.message, 'Error','Icon','error');
                return;
            end
        
            % ===== Nested helpers =====

            % แทน applyEpochTicks เดิมทั้งหมด
            function applyEpochTicks(ax, Epochs)
                % ให้แกน X เริ่มที่ 0 และติ๊กทุกๆ 20 เหมือนรูปที่ 1
                ax.XLim = [0 Epochs];
                if Epochs < 20
                    % ถ้าน้อยกว่า 20 ให้แบ่งประมาณ 4 ช่วงเพื่อไม่ถี่เกินไป
                    step = max(1, round(Epochs/4));
                    ax.XTick = 0:step:Epochs;
                else
                    ax.XTick = 0:20:Epochs;
                end
            end

            app.HistoryButton.Enable        = 'on';
        
            function stopped = trainOnePair(T,S)
                stopped = false;
                app.ThresholdState.Text   = sprintf('T = %.2f', T);
                app.SensitivityState.Text = sprintf('S = %.2f', S);
                % <<< เก็บค่า T,S ปัจจุบัน เพื่อใช้รายงานตอน Stop >>>
                app.CurrentT = T;
                app.CurrentS = S;
                drawnow limitrate;
        
                % กราฟของคู่ปัจจุบัน (เส้นเดียว)
                cla(app.UIAxes);
                cla(app.UIAxes,'reset');     % รีเซ็ตชนิดแกน
                hold(app.UIAxes, 'on');
                xlabel(app.UIAxes,'Epochs'); ylabel(app.UIAxes,'Accuracy');
                title(app.UIAxes,'Training Accuracy'); grid(app.UIAxes,'on');
                xlim(app.UIAxes,[0 Epochs]); ylim(app.UIAxes,[0 1]);
                app.hTrain = plot(app.UIAxes, NaN, NaN, 'b-', 'LineWidth', 2);
                applyEpochTicks(app.UIAxes, Epochs);
        
                tm_tmp = TsetlinMachine(NumClassesEff, NumClauses, NumFeatures, NumStates, S, T, 0);
        
                % เก็บเส้นของคู่ปัจจุบัน
                accCurveTmp = nan(1, Epochs);
                lastEp = 0;
        
                for ep = 1:Epochs
                    if app.stopFlag
                        wasStopped = true;
                        epStopped  = ep;
                        stopNotice = sprintf('⚠ Training stopped at Epoch : %d (Threshold = %.2f, Sensitivity = %.2f)\n', epStopped, T, S);
                        stopped = true;
                        break;
                    end
                    for i = 1:size(X_train,1)
                        target_class = y_train(i) + 1;
                        tm_tmp = tm_tmp.update(X_train(i,:), target_class);
                    end
                    accCurveTmp(ep) = tm_tmp.evaluate(X_train, y_train);
                    lastEp = ep;
        
                    if mod(ep, showStep) == 0 || ep == 1 || ep == Epochs
                        xx = 1:showStep:ep; if xx(end) ~= ep, xx = [xx, ep]; end
                        set(app.hTrain,'XData',xx,'YData',accCurveTmp(xx));
                        drawnow limitrate;
                    end
                end
        
                % คะแนนของคู่นี้ (0..1)
                if ~stopped
                    thisScore = tm_tmp.evaluate(X_train, y_train);
        
                    % --- เก็บไว้ทำกราฟสรุปหลังจบ sweep ---
                    sweepTs(end+1)   = T; %#ok<AGROW>
                    sweepSs(end+1)   = S; %#ok<AGROW>
                    sweepAcc(end+1)  = thisScore; %#ok<AGROW>
                    switch sweepTarget
                        case 'Threshold only'
                            sweepLabels(end+1) = string(sprintf('%.4g', T));   %#ok<AGROW>
                        case 'Sensitivity only'
                            sweepLabels(end+1) = string(sprintf('%.4g', S));   %#ok<AGROW>
                        otherwise  % 'Both'
                            sweepLabels(end+1) = string(sprintf('%.4g,%.4g', T, S)); %#ok<AGROW>
                    end
        
                    % เลือก best ทั่วช่วง
                    if thisScore > bestScore
                        bestScore     = thisScore;
                        bestModel     = tm_tmp;
                        bestT         = T;
                        bestS         = S;
                        bestAccCurve  = accCurveTmp(1:lastEp);
                        bestAccEpochs = 1:lastEp;
                    end
                end
            end
        
            function saveGraph()
                [file, path] = uiputfile('graph.png','Save Graph As');
                if isequal(file,0), return; end
                exportgraphics(app.UIAxes, fullfile(path, file));
                uialert(app.UIFigure,'Graph saved successfully!','Done','Icon','success');
            end
        
            function saveGraphPopup(axHandle)
                [file, path] = uiputfile('graph.png','Save Graph As');
                if isequal(file,0), return; end
                exportgraphics(axHandle, fullfile(path, file));
                uialert(app.UIFigure,'Graph saved successfully!','Done','Icon','success');
            end
        
            function saveParams()
                baseRows = {
                    'Number of Clauses',            num2str(NumClauses);
                    'Number of States',             num2str(NumStates);
                    'Number of Features',           num2str(NumFeatures);
                    'Number of Classes',            num2str(NumClassesEff);
                    'Epochs (requested)',           num2str(Epochs);
                    'Epochs (completed)',           num2str(epStopped);
                    'Stopped early',                iif(wasStopped,'Yes','No');
                };
        
                if strcmp(app.Switch2.Value,"Sweep Mode")
                    sweepOrder = '';
                    if exist('order','var'), sweepOrder = char(order); end
        
                    switch sweepTarget
                        case 'Threshold only'
                            rows = {
                                'Mode',                      'Sweep Mode';
                                'Sweep target',              'Threshold only';
                                'Sweep order',               sweepOrder;
                                'Threshold Range Start',     num2str(T1);
                                'Threshold Range End',       num2str(T2);
                                'Threshold Step',            num2str(Tst);
                                'Sensitivity (fixed)',       num2str(bestS,'%.6g');
                            };
                        case 'Sensitivity only'
                            rows = {
                                'Mode',                      'Sweep Mode';
                                'Sweep target',              'Sensitivity only';
                                'Sweep order',               sweepOrder;
                                'Sensitivity Range Start',   num2str(S1);
                                'Sensitivity Range End',     num2str(S2);
                                'Sensitivity Step',          num2str(Sst);
                                'Threshold (fixed)',         num2str(bestT,'%.6g');
                            };
                        otherwise % Both
                            rows = {
                                'Mode',                      'Sweep Mode';
                                'Sweep target',              'Both';
                                'Sweep order',               sweepOrder;
                                'Threshold Range Start',     num2str(T1);
                                'Threshold Range End',       num2str(T2);
                                'Threshold Step',            num2str(Tst);
                                'Sensitivity Range Start',   num2str(S1);
                                'Sensitivity Range End',     num2str(S2);
                                'Sensitivity Step',          num2str(Sst);
                            };
                    end
        
                    rows = [rows; baseRows; {
                        'Best Threshold (used)',     num2str(bestT);
                        'Best Sensitivity (used)',   num2str(bestS);
                        'Final Train Accuracy (%)',  num2str(final_train_acc,'%.4f');
                        'Final Test Accuracy (%)',   iif(~isnan(acc_test),num2str(acc_test,'%.4f'),'N/A');
                    }];
                else
                    rows = {
                        'Mode',                      'Fix Mode';
                        'Threshold (used)',          num2str(Threshold);
                        'Sensitivity (used)',        num2str(Sensitivity);
                    };
                    rows = [rows; baseRows; {
                        'Final Train Accuracy (%)',  num2str(final_train_acc,'%.4f');
                        'Final Test Accuracy (%)',   iif(~isnan(acc_test),num2str(acc_test,'%.4f'),'N/A');
                    }];
                end
        
                rows = [rows; {
                    'Training File',                safeText(app.TrainingfilehasnotbeenselectedLabel.Text);
                    'Test File',                    safeText(app.TestfilehasnotbeenselectedLabel.Text);
                }];
        
                T = cell2table(rows,'VariableNames',{'Parameter','Value'});
                [file,path] = uiputfile('parameters.xlsx','Save Parameters As');
                if isequal(file,0), return; end
                writetable(T, fullfile(path,file));
                uialert(app.UIFigure,'Parameter saved to Excel successfully!','Done','Icon','success');
            end
        
            function val = getParam(leftVal,rightVal)
                if ~isempty(strtrim(rightVal)), val = rightVal; else, val = leftVal; end
            end
            function out = iif(cond,a,b)
                if cond, out=a; else, out=b; end
            end
            function s = safeText(x)
                if isempty(x), s=''; else, s=char(string(x)); end
            end
            function y0 = toZeroBased(yin)
                if isempty(yin), y0 = yin; return; end
                yin = yin(:);
                if all(mod(yin,1)==0) && min(yin) == 1
                    y0 = yin - 1;
                else
                    y0 = yin;
                end
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            showTS(app, false);   % << ซ่อนเมื่อหยุด
            app.stopFlag = true;
            app.StopButton.Enable = 'off';
        
            % เปลี่ยนข้อความ + สีไฟเป็นส้ม
            app.TrainLabel.Text = 'Train';
            app.TrainLamp.Color = [1.0, 0.6, 0.0];   % ส้ม
            drawnow;
        
            % ตั้ง Timer → 5 วิ แล้วเปลี่ยนเป็นเทา
            if ~isempty(app.LampTimer) && isvalid(app.LampTimer)
                stop(app.LampTimer); delete(app.LampTimer);
            end
            app.LampTimer = timer( ...
                'StartDelay', 5, ...
                'ExecutionMode', 'singleShot', ...
                'TimerFcn', @(~,~) setLampGray(app));
            start(app.LampTimer);
        end

        % Value changed function: Switch2
        function Switch2ValueChanged(app, event)
            % Reset ค่าอินพุตทั้งหมดและจัดสถานะตามโหมด
            resetInputsKeepFiles(app);
            applySweepTargetEnable(app);   % อัปเดต enable/disable ให้ตรงทันที

            if strcmp(app.Switch2.Value, 'Fix Mode')
                app.Threshold_2.Enable    = 'off';
                app.Sensitivity_2.Enable  = 'off';
                app.ThresholdStep.Enable  = 'off';
                app.SensitivityStep.Enable= 'off';
                app.ToLabel.Enable        = 'off';
                app.StepLabel.Enable      = 'off';
                app.ToLabel_2.Enable      = 'off';
                app.StepLabel_2.Enable    = 'off';
                app.SweepOrder.Enable     = 'off';
                app.SweepOrderLabel.Enable= 'on';
                app.SweepTarget.Enable    = 'off';
                app.SweepTargetLabel.Enable    = 'on';
            else
                app.Threshold_2.Enable    = 'on';
                app.Sensitivity_2.Enable  = 'on';
                app.ThresholdStep.Enable  = 'on';
                app.SensitivityStep.Enable= 'on';
                app.ToLabel.Enable        = 'on';
                app.StepLabel.Enable      = 'on';
                app.ToLabel_2.Enable      = 'on';
                app.StepLabel_2.Enable    = 'on';
                app.SweepOrder.Enable     = 'on';
                app.SweepOrderLabel.Enable= 'on';
                app.SweepTarget.Enable    = 'on';
                app.SweepTargetLabel.Enable    = 'on';
            end
        end
        
        % === ฟังก์ชัน Reset ช่องทั้งหมด ===
        function resetAllInputs(app)
            % ฝั่งซ้าย
            app.Threshold_1.Value        = '';
            app.Sensitivity_1.Value      = '';
            app.NumberofClauses_1.Value  = '';
            app.NumberofStates_1.Value   = '';
            app.Epochs_1.Value           = '';
        
            % ฝั่งขวา (ช่วง)
            app.Threshold_2.Value        = '';
            app.Sensitivity_2.Value      = '';
        
            % Step
            app.ThresholdStep.Value      = '';
            app.SensitivityStep.Value    = '';

            app.TrainingfilehasnotbeenselectedLabel.Text               = '';
            app.TestfilehasnotbeenselectedLabel.Text             = '';
        
            % ล้างกราฟ
            cla(app.UIAxes);
            title(app.UIAxes,''); xlabel(app.UIAxes,'Epochs'); ylabel(app.UIAxes,'Accuracy'); grid(app.UIAxes,'on');
        
            % ปิด Lamp
            app.TrainLamp.Color = [0.5 0.5 0.5];
        
            % ล้างโมเดล
            app.TrainedModel = [];
            app.StopButton.Enable = 'off';
        end

        % Button pushed function: SelectTestFileButton
        function SelectTestFileButtonPushed(app, event)
            [file, path] = uigetfile({ ...
                '*.csv',  'CSV Files (*.csv)'; ...
                '*.xlsx', 'Excel Files (*.xlsx)'}, ...
                'Select Test File');
        
            if isequal(file,0)
                if isempty(app.XTest) || isempty(app.yTest)
                    app.TestfilehasnotbeenselectedLabel.Text = 'Test file has not been selected.';
                end
                return;
            end
        
            fullPath = fullfile(path,file);
        
            [data, errmsg] = readFlexibleFile(app, fullPath);
            if isempty(data)
                uialert(app.UIFigure, "Failed to read test file: " + errmsg, 'Error', 'Icon','error');
                return;
            end
            if size(data,2) < 2
                uialert(app.UIFigure,'The file must have at least 2 columns (features + label).','Invalid file format','Icon','error');
                return;
            end
        
            % แยก X/Y อัตโนมัติ (รองรับ label อยู่หัว/ท้าย)
            [Xt, yt] = splitXY_auto(app, data);
        
            app.TestfilehasnotbeenselectedLabel.Text = file;
            app.XTest = Xt;
            app.yTest = yt;
        
            disp("Test file loaded successfully: " + file);
        end

        % Value changed function: SweepTarget
        function SweepTargetValueChanged(app, event)
            % ถ้าไม่ใช่ Sweep Mode -> ปิดฝั่งขวาทั้งหมด แล้วออก
            if ~strcmp(app.Switch2.Value, 'Sweep Mode')
                app.Threshold_2.Enable    = 'off';
                app.ThresholdStep.Enable  = 'off';
                app.Sensitivity_2.Enable  = 'off';
                app.SensitivityStep.Enable= 'off';
                % ลำดับไม่เกี่ยวเมื่อไม่สวีป
                app.SweepOrder.Enable     = 'off';
                return;
            end
        
            % อยู่ใน Sweep Mode -> เปิด/ปิดตามเป้าหมายการสวีป
            switch app.SweepTarget.Value
                case 'Threshold only'
                    app.Threshold_2.Enable    = 'on';
                    app.ThresholdStep.Enable  = 'on';
                    app.Sensitivity_2.Enable  = 'off';
                    app.SensitivityStep.Enable= 'off';
                    app.SweepOrder.Enable     = 'off';   % ลำดับไม่จำเป็น
                case 'Sensitivity only'
                    app.Threshold_2.Enable    = 'off';
                    app.ThresholdStep.Enable  = 'off';
                    app.Sensitivity_2.Enable  = 'on';
                    app.SensitivityStep.Enable= 'on';
                    app.SweepOrder.Enable     = 'off';   % ลำดับไม่จำเป็น
                otherwise % 'Both'
                    app.Threshold_2.Enable    = 'on';
                    app.ThresholdStep.Enable  = 'on';
                    app.Sensitivity_2.Enable  = 'on';
                    app.SensitivityStep.Enable= 'on';
                    app.SweepOrder.Enable     = 'on';    % ลำดับมีผล
            end
            % ทำงานเฉพาะตอนอยู่ใน Sweep Mode
            if ~strcmp(app.Switch2.Value, 'Sweep Mode')
                applySweepTargetEnable(app);
                return;
            end
        
            % ล้างค่าช่องที่ไม่ได้ใช้ แล้วคงสถานะ enable/disable ให้ถูกต้อง
            clearUnusedSweepFields(app);   % ล้างค่าที่ไม่ได้ใช้
            applySweepTargetEnable(app);   % และคง disable ช่องที่ไม่ใช้
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.LampTimer) && isvalid(app.LampTimer)
                stop(app.LampTimer); delete(app.LampTimer);
            end
            if app.IsTraining
                % ซ่อนหน้าต่างไปก่อน และสั่งหยุดเทรน
                app.PendingClose = true;
                app.stopFlag = true;
                app.UIFigure.Visible = 'off';
                drawnow;
                % ไม่ delete ที่นี่ เพื่อกันลูปไปชน handle ที่ถูกลบ
                return;
            end
            delete(app);
        end

        % Button pushed function: HistoryButton
        function HistoryButtonPushed(app, event)
            app.showHistory();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Epochs')
            ylabel(app.UIAxes, 'Accuracy')
            zlabel(app.UIAxes, 'Z')
            app.ControlPanelLabel = uilabel(app.UIFigure);
            app.ControlPanelLabel.Text = 'Control Panel';
            app.Threshold_2 = uieditfield(app.UIFigure, 'text');
            app.Sensitivity_2 = uieditfield(app.UIFigure, 'text');
            app.ThresholdTEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdTEditFieldLabel.Text = 'Threshold (T):';
            app.Threshold_1 = uieditfield(app.UIFigure, 'text');
            app.SensitivitySLabel = uilabel(app.UIFigure);
            app.SensitivitySLabel.Text = 'Sensitivity (S):';
            app.Sensitivity_1 = uieditfield(app.UIFigure, 'text');
            app.NumberofClausesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofClausesEditFieldLabel.Text = 'Number of Clauses :';
            app.NumberofClauses_1 = uieditfield(app.UIFigure, 'text');
            app.NumberofStatesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofStatesEditFieldLabel.Text = 'Number of States :';
            app.NumberofStates_1 = uieditfield(app.UIFigure, 'text');
            app.NumberofClassesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofClassesEditFieldLabel.Text = 'Number of Classes :';
            app.NumberofFeaturesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofFeaturesEditFieldLabel.Text = 'Number of Features :';
            app.NumberofFeatures_1 = uieditfield(app.UIFigure, 'text');
            app.NumberofClasses_1 = uieditfield(app.UIFigure, 'text');
            app.EpochsEditFieldLabel = uilabel(app.UIFigure);
            app.EpochsEditFieldLabel.Text = 'Epochs :';
            app.Epochs_1 = uieditfield(app.UIFigure, 'text');
            app.Switch2 = uiswitch(app.UIFigure, 'slider');
            app.Switch2.Items = {'Sweep Mode', 'Fix Mode'};
            app.Switch2.ValueChangedFcn = createCallbackFcn(app, @Switch2ValueChanged, true);
            app.Switch2.Value = 'Sweep Mode';
            app.TrainButton = uibutton(app.UIFigure, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.TrainLamp = uilamp(app.UIFigure);
            app.SelectedFileLabel = uibutton(app.UIFigure, 'push');
            app.SelectedFileLabel.ButtonPushedFcn = createCallbackFcn(app, @SelectedFileLabelPushed, true);
            app.SelectedFileLabel.Text = 'Select Training File';
            app.SelectTestFileButton = uibutton(app.UIFigure, 'push');
            app.SelectTestFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTestFileButtonPushed, true);
            app.SelectTestFileButton.Text = 'Select Test File';
            app.TrainingfilehasnotbeenselectedLabel = uilabel(app.UIFigure);
            app.TrainingfilehasnotbeenselectedLabel.Text = 'Training file has not been selected.';
            app.ThresholdStep = uieditfield(app.UIFigure, 'text');
            app.SensitivityStep = uieditfield(app.UIFigure, 'text');
            app.TestfilehasnotbeenselectedLabel = uilabel(app.UIFigure);
            app.TestfilehasnotbeenselectedLabel.Text = 'Test file has not been selected.';
            app.ToLabel = uilabel(app.UIFigure);
            app.ToLabel.Text = 'To';
            app.StepLabel = uilabel(app.UIFigure);
            app.StepLabel.Text = 'Step :';
            app.ToLabel_2 = uilabel(app.UIFigure);
            app.ToLabel_2.Text = 'To';
            app.StepLabel_2 = uilabel(app.UIFigure);
            app.StepLabel_2.Text = 'Step :';
            app.TrainLabel = uilabel(app.UIFigure);
            app.TrainLabel.Text = 'Train';
            app.ThresholdState = uilabel(app.UIFigure);
            app.ThresholdState.Text = '';
            app.SensitivityState = uilabel(app.UIFigure);
            app.SensitivityState.Text = '';
            app.ShowEpochsLabel = uilabel(app.UIFigure);
            app.ShowEpochsLabel.Text = 'Plot (epochs):';
            app.ShowEpochs = uieditfield(app.UIFigure, 'text');
            app.SweepOrder = uidropdown(app.UIFigure);
            app.SweepOrder.Items = {'T→S', 'S→T'};
            app.SweepOrder.Value = 'T→S';
            app.SweepOrderLabel = uilabel(app.UIFigure);
            app.SweepOrderLabel.Text = 'SweepOrder';
            app.SweepTargetLabel = uilabel(app.UIFigure);
            app.SweepTargetLabel.Text = 'Sweep Target';
            app.SweepTarget = uidropdown(app.UIFigure);
            app.SweepTarget.Items = {'Both', 'Threshold only', 'Sensitivity only'};
            app.SweepTarget.ValueChangedFcn = createCallbackFcn(app, @SweepTargetValueChanged, true);
            app.SweepTarget.Value = 'Both';
            app.HistoryButton = uibutton(app.UIFigure, 'push');
            app.HistoryButton.ButtonPushedFcn = createCallbackFcn(app, @HistoryButtonPushed, true);
            app.HistoryButton.Text = 'History';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TsetlinGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end